classdef MatrixCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatrixappUIFigure              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ArithmeticTab                  matlab.ui.container.Tab
        MatrixCalculatorPanel          matlab.ui.container.Panel
        ClearResButton                 matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        MULLamp                        matlab.ui.control.Lamp
        SUBLamp                        matlab.ui.control.Lamp
        ADDLamp                        matlab.ui.control.Lamp
        Panel                          matlab.ui.container.Panel
        SUBSwitch                      matlab.ui.control.ToggleSwitch
        SUBLabel                       matlab.ui.control.Label
        MULSwitch                      matlab.ui.control.ToggleSwitch
        MULSwitchLabel                 matlab.ui.control.Label
        ADDSwitch                      matlab.ui.control.ToggleSwitch
        ADDSwitchLabel                 matlab.ui.control.Label
        numColSpinner2                 matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel_2  matlab.ui.control.Label
        numRowSpinner2                 matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel_2     matlab.ui.control.Label
        Matrix2Label                   matlab.ui.control.Label
        Matrix1Label                   matlab.ui.control.Label
        numColSpinner                  matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel    matlab.ui.control.Label
        numRowSpinner                  matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel       matlab.ui.control.Label
        DisplayPanel2                  matlab.ui.container.Panel
        CalculateButton                matlab.ui.control.Button
        DisplayButton                  matlab.ui.control.Button
        DisplayPanel1                  matlab.ui.container.Panel
        MatrixFunctionsTab             matlab.ui.container.Tab
        MatrixFunctionsPanel           matlab.ui.container.Panel
        InverseButton                  matlab.ui.control.Button
        DeterminantButton              matlab.ui.control.Button
        TransposeButton                matlab.ui.control.Button
        ClearResButton_2               matlab.ui.control.Button
        ClearAllButton_2               matlab.ui.control.Button
        ResultPanel_2                  matlab.ui.container.Panel
        Matrix1Label_2                 matlab.ui.control.Label
        numColSpinner_2                matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel_3  matlab.ui.control.Label
        numRowSpinner_2                matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel_3     matlab.ui.control.Label
        DisplayButton_2                matlab.ui.control.Button
        DisplayPanel1_2                matlab.ui.container.Panel
    end

    
    properties (Access = private)
        numRowSp1
        numColSp1
        numRowSp2
        numColSp2
        numRowSp3
        numColSp3
        children1
        children2
        children3
        matrixStrArray1
        matrixStrArray2
        matrixStrArray3
        operation
        matrixA
        matrixB
        matrixC
        resRow
        resCol
        resRow_2
        resCol_2
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numRowSp1 = 1;
            app.numColSp1 = 1;
            app.numRowSp2 = 1;
            app.numColSp2 = 1;
            app.numRowSp3 = 1;
            app.numColSp3 = 1;
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65'; % gray
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner
        function numRowSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner
        function numColSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner2
        function numRowSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner2
        function numColSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end
            
            % Matrix 1
            paddingX = 5;
            paddingY = 5;

            numRows1 = app.numRowSp1;
            numCols1 = app.numColSp1;
            
            panelWidth1 = app.DisplayPanel1.Position(3);
            panelHeight1 = app.DisplayPanel1.Position(4);

            fieldWidth1 = (panelWidth1 - (paddingX * (numCols1 - 1))) / numCols1;
            fieldHeight1 = (panelHeight1 - (paddingY * (numRows1 - 1))) / numRows1;
 
            for row1 = 1:numRows1
                for col1 = 1:numCols1
                    field = uieditfield(app.DisplayPanel1,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX1 = (col1 - 1) * (fieldWidth1 + paddingX);
                    fieldY1 = panelHeight1 - row1 * (fieldHeight1 + paddingY);

                    if row1 == 1
                        fieldY1 = panelHeight1 - row1 * fieldHeight1;
                    end
                    
                    field.Tag = sprintf('A%d%d', row1, col1);
                    field.Position = [fieldX1, fieldY1, fieldWidth1, fieldHeight1];
                    pause(0.05)
                end
            end

            % Matrix 2
            numRows2 = app.numRowSp2;
            numCols2 = app.numColSp2;

            panelWidth2 = app.DisplayPanel2.Position(3);
            panelHeight2 = app.DisplayPanel2.Position(4);

            fieldWidth2 = (panelWidth2 - (paddingX * (numCols2 - 1))) / numCols2;
            fieldHeight2 = (panelHeight2 - (paddingY * (numRows2 - 1))) / numRows2;
 
            for row2 = 1:numRows2
                for col2 = 1:numCols2
                    field = uieditfield(app.DisplayPanel2,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX2 = (col2 - 1) * (fieldWidth2 + paddingX);
                    fieldY2 = panelHeight2 - row2 * (fieldHeight2 + paddingY);

                    if row2 == 1
                        fieldY2 = panelHeight2 - row2 * fieldHeight2;
                    end
                    
                    field.Tag = sprintf('B%d%d', row2, col2);
                    field.Position = [fieldX2, fieldY2, fieldWidth2, fieldHeight2];
                    pause(.05)
                end
            end

            % Matrix 1 Children Tagging
            app.children1 = app.DisplayPanel1.Children;

            for idx = 1:length(app.children1)
                childIdx1 = app.children1(idx).Tag;
                disp(['The tag of Matrix 1 component ' num2str(idx) ' is: ' childIdx1]);
            end

            % Matrix 2 Children Tagging
            app.children2 = app.DisplayPanel2.Children;

            for idx = 1:length(app.children2)
                childIdx2 = app.children2(idx).Tag;
                disp(['The tag of Matrix 2 component ' num2str(idx) ' is: ' childIdx2]);
            end

            % Clear All Button
            app.ClearAllButton.Enable = 'on';

            %Lamp
            if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                app.ADDLamp.Color = 'g';
                app.SUBLamp.Color = 'g';
                app.ADDSwitch.Enable = "on";
                app.SUBSwitch.Enable = "on";
            else
                app.ADDLamp.Color = 'r';
                app.SUBLamp.Color = 'r';
                app.ADDSwitch.Value = 'Off';
                app.SUBSwitch.Value = 'Off';
            end
            
            if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                app.MULLamp.Color = 'g';
                app.MULSwitch.Enable = "on";
            else
                app.MULLamp.Color = 'r';
                app.MULSwitch.Value = 'Off';
            end

            % Calculate Button
            if (strcmp(app.MULSwitch.Value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            else
                app.CalculateButton.Enable = "on";
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Matrix 1
            app.matrixStrArray1 = '';
            matrix1 = zeros(app.numRowSp1, app.numColSp1);
            
            for row1 = 1:app.numRowSp1
                for col1 = 1:app.numColSp1
                    idx1 = sprintf('A%d%d', row1, col1);
                    uiComponent1 = findobj(app.MatrixappUIFigure, 'Tag', idx1);
                    currentValue1 = uiComponent1.Value;
                    matrix1(row1, col1) = currentValue1;
                end
            end

            app.matrixA = matrix1;

            % Matrix 2
            app.matrixStrArray2 = '';
            matrix2 = zeros(app.numRowSp2, app.numColSp2);

            for row2 = 1:app.numRowSp2
                for col2 = 1:app.numColSp2
                    idx2 = sprintf('B%d%d', row2, col2);
                    uiComponent2 = findobj(app.MatrixappUIFigure, 'Tag', idx2);
                    currentValue2 = uiComponent2.Value;
                    matrix2(row2, col2) = currentValue2;
                end
            end

            app.matrixB = matrix2;
            
            % for cli checking
            disp("Matrix A")
            disp(app.matrixA)
            disp("Matrix B")
            disp(app.matrixB)


            % Matrices Calculation
            if strcmp(app.ADDSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        sumOfMatrices = app.matrixA + app.matrixB;
                        resultMatrix = sumOfMatrices;
                        [app.resRow, app.resCol] = size(sumOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.SUBSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        difOfMatrices = app.matrixA - app.matrixB;
                        resultMatrix = difOfMatrices;
                        [app.resRow, app.resCol] = size(difOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.MULSwitch.Value, 'On')
                    if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                        prodOfMatrices = app.matrixA * app.matrixB;
                        resultMatrix = prodOfMatrices;
                        [app.resRow, app.resCol] = size(prodOfMatrices);
                    end
            else
                msgbox('No Operation Selected. Please Select an Operation.', 'Error: No Operation Detected.')
                app.CalculateButton.Enable = "off";
            end

            % displaying the result
            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow;
            numCols3 = app.resCol;
            
            panelWidth3 = app.Panel.Position(3);
            panelHeight3 = app.Panel.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.Panel,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Clear All Button
            app.ClearResButton.Enable = "on";
            app.ClearAllButton.Enable = 'on';
        end

        % Value changed function: ADDSwitch
        function ADDSwitchValueChanged(app, event)
            value = app.ADDSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.MULSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.MULSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.MULSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: MULSwitch
        function MULSwitchValueChanged(app, event)
            value = app.MULSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: SUBSwitch
        function SUBSwitchValueChanged(app, event)
            value = app.SUBSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.MULSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.MULSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.MULSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Button pushed function: ClearResButton
        function ClearResButtonPushed(app, event)
            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end
            
            app.CalculateButton.Enable = "off";
            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.ClearResButton.Enable = "off";
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner_2
        function numRowSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp3 = changingValue;
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end

        % Value changing function: numColSpinner_2
        function numColSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp3 = changingValue;
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end

        % Button pushed function: DisplayButton_2
        function DisplayButton_2Pushed(app, event)
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end
            
            % Matrix 3
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.numRowSp3;
            numCols3 = app.numColSp3;
            
            panelWidth3 = app.DisplayPanel1_2.Position(3);
            panelHeight3 = app.DisplayPanel1_2.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row1 = 1:numRows3
                for col1 = 1:numCols3
                    field = uieditfield(app.DisplayPanel1_2,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX3 = (col1 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row1 * (fieldHeight3 + paddingY);

                    if row1 == 1
                        fieldY3 = panelHeight3 - row1 * fieldHeight3;
                    end
                    
                    field.Tag = sprintf('A%d%d', row1, col1);
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                    pause(0.05)
                end
            end

            % Matrix 3 Children Tagging
            app.children3 = app.DisplayPanel1_2.Children;

            for idx = 1:length(app.children3)
                childIdx3 = app.children3(idx).Tag;
                disp(['The tag of Matrix 1 component ' num2str(idx) ' is: ' childIdx3]);
            end

            app.children2 = app.DisplayPanel2.Children;

            % Button Interactions
            app.TransposeButton.Enable = "on";
            if app.numRowSp3 == app.numColSp3
                app.DeterminantButton.Enable = "on";
                app.InverseButton.Enable = "on";
            end
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: TransposeButton
        function TransposeButtonPushed(app, event)
            % Matrix 3
            app.matrixStrArray3 = '';
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixappUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Transposing
            app.matrixC = matrix3';
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Transposed Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3);
            panelHeight3 = app.ResultPanel_2.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: DeterminantButton
        function DeterminantButtonPushed(app, event)
            % Matrix 3
            app.matrixStrArray3 = '';
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixappUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Determinant
            app.matrixC = det(matrix3);
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Determinant of Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3);
            panelHeight3 = app.ResultPanel_2.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Matrix 3
            app.matrixStrArray3 = '';
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixappUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Inversing
            if det(matrix3) == 0
                if ~isempty(app.ResultPanel_2)
                    delete(app.ResultPanel_2.Children)
                end
                msgbox("Warning: The matrix is singular thus the value of determinants is zero and the matrix of the inverse does not exist.", "Error")
                return;
            end
            app.matrixC = inv(matrix3);
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Determinant of Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3);
            panelHeight3 = app.ResultPanel_2.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: ClearResButton_2
        function ClearResButton_2Pushed(app, event)
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.ClearResButton_2.Enable = "off";
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end

            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatrixappUIFigure = uifigure('Visible', 'off');
            app.MatrixappUIFigure.Name = 'Matrix app';
            app.TabGroup = uitabgroup(app.MatrixappUIFigure);
            app.ArithmeticTab = uitab(app.TabGroup);
            app.ArithmeticTab.Title = 'Arithmetic';
            app.MatrixCalculatorPanel = uipanel(app.ArithmeticTab);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.DisplayPanel1 = uipanel(app.MatrixCalculatorPanel);
            app.DisplayButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.CalculateButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DisplayPanel2 = uipanel(app.MatrixCalculatorPanel);
            app.NumberofRowsSpinnerLabel = uilabel(app.MatrixCalculatorPanel);
            app.NumberofRowsSpinnerLabel.Text = 'Number of Rows';
            app.numRowSpinner = uispinner(app.MatrixCalculatorPanel);
            app.numRowSpinner.ValueChangingFcn = createCallbackFcn(app, @numRowSpinnerValueChanging, true);
            app.numRowSpinner.Limits = [1 5];
            app.numRowSpinner.Value = 1;
            app.NumberofColumnsSpinnerLabel = uilabel(app.MatrixCalculatorPanel);
            app.NumberofColumnsSpinnerLabel.Text = 'Number of Columns';
            app.numColSpinner = uispinner(app.MatrixCalculatorPanel);
            app.numColSpinner.ValueChangingFcn = createCallbackFcn(app, @numColSpinnerValueChanging, true);
            app.numColSpinner.Limits = [1 5];
            app.numColSpinner.Value = 1;
            app.Matrix1Label = uilabel(app.MatrixCalculatorPanel);
            app.Matrix1Label.Text = 'Matrix 1';
            app.Matrix2Label = uilabel(app.MatrixCalculatorPanel);
            app.Matrix2Label.Text = 'Matrix 2';
            app.NumberofRowsSpinnerLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.NumberofRowsSpinnerLabel_2.Text = 'Number of Rows';
            app.numRowSpinner2 = uispinner(app.MatrixCalculatorPanel);
            app.numRowSpinner2.ValueChangingFcn = createCallbackFcn(app, @numRowSpinner2ValueChanging, true);
            app.numRowSpinner2.Limits = [1 5];
            app.numRowSpinner2.Value = 1;
            app.NumberofColumnsSpinnerLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.NumberofColumnsSpinnerLabel_2.Text = 'Number of Columns';
            app.numColSpinner2 = uispinner(app.MatrixCalculatorPanel);
            app.numColSpinner2.ValueChangingFcn = createCallbackFcn(app, @numColSpinner2ValueChanging, true);
            app.numColSpinner2.Limits = [1 5];
            app.numColSpinner2.Value = 1;
            app.ADDSwitchLabel = uilabel(app.MatrixCalculatorPanel);
            app.ADDSwitchLabel.Text = 'ADD';
            app.ADDSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.ADDSwitch.ValueChangedFcn = createCallbackFcn(app, @ADDSwitchValueChanged, true);
            app.MULSwitchLabel = uilabel(app.MatrixCalculatorPanel);
            app.MULSwitchLabel.Text = 'MUL';
            app.MULSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.MULSwitch.ValueChangedFcn = createCallbackFcn(app, @MULSwitchValueChanged, true);
            app.SUBLabel = uilabel(app.MatrixCalculatorPanel);
            app.SUBLabel.Text = 'SUB';
            app.SUBSwitch = uiswitch(app.MatrixCalculatorPanel, 'toggle');
            app.SUBSwitch.ValueChangedFcn = createCallbackFcn(app, @SUBSwitchValueChanged, true);
            app.Panel = uipanel(app.MatrixCalculatorPanel);
            app.ADDLamp = uilamp(app.MatrixCalculatorPanel);
            app.SUBLamp = uilamp(app.MatrixCalculatorPanel);
            app.MULLamp = uilamp(app.MatrixCalculatorPanel);
            app.ClearAllButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ClearResButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.ClearResButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResButtonPushed, true);
            app.ClearResButton.Text = 'Clear Result';
            app.MatrixFunctionsTab = uitab(app.TabGroup);
            app.MatrixFunctionsTab.Title = 'Matrix Functions';
            app.MatrixFunctionsPanel = uipanel(app.MatrixFunctionsTab);
            app.MatrixFunctionsPanel.Title = 'Matrix Functions';
            app.DisplayPanel1_2 = uipanel(app.MatrixFunctionsPanel);
            app.DisplayButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.DisplayButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplayButton_2Pushed, true);
            app.DisplayButton_2.Text = 'Display';
            app.NumberofRowsSpinnerLabel_3 = uilabel(app.MatrixFunctionsPanel);
            app.NumberofRowsSpinnerLabel_3.Text = 'Number of Rows';
            app.numRowSpinner_2 = uispinner(app.MatrixFunctionsPanel);
            app.numRowSpinner_2.ValueChangingFcn = createCallbackFcn(app, @numRowSpinner_2ValueChanging, true);
            app.numRowSpinner_2.Limits = [1 5];
            app.numRowSpinner_2.Value = 1;
            app.NumberofColumnsSpinnerLabel_3 = uilabel(app.MatrixFunctionsPanel);
            app.NumberofColumnsSpinnerLabel_3.Text = 'Number of Columns';
            app.numColSpinner_2 = uispinner(app.MatrixFunctionsPanel);
            app.numColSpinner_2.ValueChangingFcn = createCallbackFcn(app, @numColSpinner_2ValueChanging, true);
            app.numColSpinner_2.Limits = [1 5];
            app.numColSpinner_2.Value = 1;
            app.Matrix1Label_2 = uilabel(app.MatrixFunctionsPanel);
            app.Matrix1Label_2.Text = 'Matrix 1';
            app.ResultPanel_2 = uipanel(app.MatrixFunctionsPanel);
            app.ClearAllButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Enable = 'off';
            app.ClearAllButton_2.Text = 'Clear All';
            app.ClearResButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.ClearResButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearResButton_2Pushed, true);
            app.ClearResButton_2.Enable = 'off';
            app.ClearResButton_2.Text = 'Clear Result';
            app.TransposeButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.TransposeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeButtonPushed, true);
            app.TransposeButton.Enable = 'off';
            app.TransposeButton.Text = 'Transpose';
            app.DeterminantButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.DeterminantButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminantButtonPushed, true);
            app.DeterminantButton.Enable = 'off';
            app.DeterminantButton.Text = 'Determinant';
            app.InverseButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Enable = 'off';
            app.InverseButton.Text = 'Inverse';
            % Show the figure after all components are created
            app.MatrixappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatrixappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatrixappUIFigure)
        end
    end
end